classdef parametricCycleAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        AnalysisResultPanel             matlab.ui.container.Panel
        AnalysisResultPanelGrid         matlab.ui.container.GridLayout
        AnalysisResultPanelTabGroup     matlab.ui.container.TabGroup
        TabelaTab                       matlab.ui.container.Tab
        TabelaTabGrid                   matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        ExportTableButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EngineInputsPanel               matlab.ui.container.Panel
        EngineInputsPanelGrid           matlab.ui.container.GridLayout
        InputsCOMPILARButton            matlab.ui.control.Button
        ManterInputsComunsCheckbox      matlab.ui.control.CheckBox
        EngineInputsPanelTabGroup       matlab.ui.container.TabGroup
        RamjetIdealTab                  matlab.ui.container.Tab
        RamjetIdealTabGrid              matlab.ui.container.GridLayout
        IR_FlightRegimeGridCheckbox     matlab.ui.container.GridLayout
        IR_TemperatureCheckbox          matlab.ui.control.CheckBox
        IR_AltitudeCheckbox             matlab.ui.control.CheckBox
        IR_FlightRegimeEditField        matlab.ui.control.EditField
        IR_FlightRegimeEditFieldLabel   matlab.ui.control.Label
        IR_Tt4EditField                 matlab.ui.control.EditField
        IR_Tt4EditFieldLabel            matlab.ui.control.Label
        IR_HprEditField                 matlab.ui.control.NumericEditField
        IR_HprEditFieldLabel            matlab.ui.control.Label
        IR_CpcEditField                 matlab.ui.control.NumericEditField
        IR_CpcEditFieldLabel            matlab.ui.control.Label
        IR_GammaCEditField              matlab.ui.control.NumericEditField
        IR_GammaCEditFieldLabel         matlab.ui.control.Label
        IR_MachNumberEditField          matlab.ui.control.EditField
        IR_MachNumberEditFieldLabel     matlab.ui.control.Label
        TurbojetIdealTab                matlab.ui.container.Tab
        TurbojetIdealTabGrid            matlab.ui.container.GridLayout
        ITj_AfterburnerCheckbox         matlab.ui.control.CheckBox
        ITj_Tt7EditField                matlab.ui.control.NumericEditField
        ITj_Tt7EditFieldLabel           matlab.ui.control.Label
        ITj_PiCEditField                matlab.ui.control.NumericEditField
        ITj_PiCEditFieldLabel           matlab.ui.control.Label
        ITj_FlightRegimeGridCheckbox    matlab.ui.container.GridLayout
        ITj_TemperatureCheckbox         matlab.ui.control.CheckBox
        ITj_AltitudeCheckbox            matlab.ui.control.CheckBox
        ITj_FlightRegimeEditField       matlab.ui.control.EditField
        ITj_FlightRegimeEditFieldLabel  matlab.ui.control.Label
        ITj_Tt4EditField                matlab.ui.control.EditField
        ITj_Tt4EditFieldLabel           matlab.ui.control.Label
        ITj_HprEditField                matlab.ui.control.NumericEditField
        ITj_HprEditFieldLabel           matlab.ui.control.Label
        ITj_CpcEditField                matlab.ui.control.NumericEditField
        ITj_CpcEditFieldLabel           matlab.ui.control.Label
        ITj_GammaCEditField             matlab.ui.control.NumericEditField
        ITj_GammaCEditFieldLabel        matlab.ui.control.Label
        ITj_MachNumberEditField         matlab.ui.control.EditField
        ITj_MachNumberEditFieldLabel    matlab.ui.control.Label
        TurbofanIdealTab                matlab.ui.container.Tab
        TurbofanIdealTabGrid            matlab.ui.container.GridLayout
        ITf_BPREditField                matlab.ui.control.NumericEditField
        ITf_BPREditFieldLabel           matlab.ui.control.Label
        ITf_PifEditField                matlab.ui.control.NumericEditField
        ITf_PifEditFieldLabel           matlab.ui.control.Label
        ITf_PicEditField                matlab.ui.control.NumericEditField
        ITf_PicEditFieldLabel           matlab.ui.control.Label
        ITf_FlightRegimeGridCheckbox    matlab.ui.container.GridLayout
        ITf_AltitudeCheckbox            matlab.ui.control.CheckBox
        ITf_TemperatureCheckbox         matlab.ui.control.CheckBox
        ITf_FlightRegimeEditField       matlab.ui.control.EditField
        ITf_FlightRegimeEditFieldLabel  matlab.ui.control.Label
        ITf_Tt4EditField                matlab.ui.control.EditField
        ITf_Tt4EditFieldLabel           matlab.ui.control.Label
        ITf_HprEditField                matlab.ui.control.NumericEditField
        ITf_HprEditFieldLabel           matlab.ui.control.Label
        ITf_CpcEditField                matlab.ui.control.NumericEditField
        ITf_CpcEditFieldLabel           matlab.ui.control.Label
        ITf_GammaCEditField             matlab.ui.control.NumericEditField
        ITf_GammaCEditFieldLabel        matlab.ui.control.Label
        ITf_MachNumberEditField         matlab.ui.control.EditField
        ITf_MachNumberEditFieldLabel    matlab.ui.control.Label
        TurbopropIdealTab               matlab.ui.container.Tab
        TurbopropIdealTabGrid           matlab.ui.container.GridLayout
        ITp_ConventionalAdvancedButton  matlab.ui.control.StateButton
        ITp_FlightRegimeGridCheckbox    matlab.ui.container.GridLayout
        ITp_AltitudeCheckbox            matlab.ui.control.CheckBox
        ITp_TemperatureCheckbox         matlab.ui.control.CheckBox
        ITp_FlightRegimeEditField       matlab.ui.control.EditField
        ITp_CpcEditField                matlab.ui.control.NumericEditField
        ITp_CpcEditFieldLabel           matlab.ui.control.Label
        ITp_GammaCEditField             matlab.ui.control.NumericEditField
        ITp_GammaCEditFieldLabel        matlab.ui.control.Label
        ITp_FlightRegimeEditFieldLabel  matlab.ui.control.Label
        ITp_MachNumberEditField         matlab.ui.control.EditField
        ITp_MachNumberEditFieldLabel    matlab.ui.control.Label
        TurbojetRealTab                 matlab.ui.container.Tab
        TurbojetRealTabGrid             matlab.ui.container.GridLayout
        RTj_AfterburnerPanel            matlab.ui.container.Panel
        RTj_AfterburnerPanelGrid        matlab.ui.container.GridLayout
        RTj_EtaabEditField              matlab.ui.control.NumericEditField
        RTj_EtaabEditFieldLabel         matlab.ui.control.Label
        RTj_PiabEditField               matlab.ui.control.NumericEditField
        RTj_PiabEditFieldLabel          matlab.ui.control.Label
        RTj_CpabEditField               matlab.ui.control.NumericEditField
        RTj_CpabEditFieldLabel          matlab.ui.control.Label
        RTj_GammaABEditField            matlab.ui.control.NumericEditField
        RTj_GammaABEditFieldLabel       matlab.ui.control.Label
        RTj_Tt7EditField                matlab.ui.control.NumericEditField
        RTj_Tt7EditFieldLabel           matlab.ui.control.Label
        RTj_AfterburnerCheckbox         matlab.ui.control.CheckBox
        RTj_EtamEditField               matlab.ui.control.NumericEditField
        RTj_EtamEditFieldLabel          matlab.ui.control.Label
        RTj_EtabEditField               matlab.ui.control.NumericEditField
        RTj_EtabEditFieldLabel          matlab.ui.control.Label
        RTj_EtEditField                 matlab.ui.control.NumericEditField
        RTj_EtEditFieldLabel            matlab.ui.control.Label
        RTj_EcEditField                 matlab.ui.control.NumericEditField
        RTj_EcEditFieldLabel            matlab.ui.control.Label
        RTj_PinEditField                matlab.ui.control.NumericEditField
        RTj_PinEditFieldLabel           matlab.ui.control.Label
        RTj_PibEditField                matlab.ui.control.NumericEditField
        RTj_PibEditFieldLabel           matlab.ui.control.Label
        RTj_PidmaxEditField             matlab.ui.control.NumericEditField
        RTj_PidmaxEditFieldLabel        matlab.ui.control.Label
        RTj_PicEditField                matlab.ui.control.EditField
        RTj_PicEditFieldLabel           matlab.ui.control.Label
        RTj_P0P9EditField               matlab.ui.control.NumericEditField
        RTj_P0P9EditFieldLabel          matlab.ui.control.Label
        RTj_CptEditField                matlab.ui.control.NumericEditField
        RTj_CptEditFieldLabel           matlab.ui.control.Label
        RTj_GammaTEditField             matlab.ui.control.NumericEditField
        RTj_GammaTEditFieldLabel        matlab.ui.control.Label
        RTj_FlightRegimeGridCheckbox    matlab.ui.container.GridLayout
        RTj_TemperatureCheckbox         matlab.ui.control.CheckBox
        RTj_AltitudeCheckbox            matlab.ui.control.CheckBox
        RTj_FlightRegimeEditField       matlab.ui.control.EditField
        RTj_FlightRegimeEditFieldLabel  matlab.ui.control.Label
        RTj_Tt4EditField                matlab.ui.control.EditField
        RTj_Tt4EditFieldLabel           matlab.ui.control.Label
        RTj_HprEditField                matlab.ui.control.NumericEditField
        RTj_HprEditFieldLabel           matlab.ui.control.Label
        RTj_CpcEditField                matlab.ui.control.NumericEditField
        RTj_CpcEditFieldLabel           matlab.ui.control.Label
        RTj_GammaCEditField             matlab.ui.control.NumericEditField
        RTj_GammaCEditFieldLabel        matlab.ui.control.Label
        RTj_MachNumberEditField         matlab.ui.control.EditField
        RTj_MachNumberEditFieldLabel    matlab.ui.control.Label
        TurbofanRealTab                 matlab.ui.container.Tab
        TurbofanRealTabGrid             matlab.ui.container.GridLayout
        RTf_EtamEditField               matlab.ui.control.NumericEditField
        RTf_EtamEditFieldLabel          matlab.ui.control.Label
        RTf_EtabEditField               matlab.ui.control.NumericEditField
        RTf_EtabEditFieldLabel          matlab.ui.control.Label
        RTf_EfEditField                 matlab.ui.control.NumericEditField
        RTf_EfEditFieldLabel            matlab.ui.control.Label
        RTf_EtEditField                 matlab.ui.control.NumericEditField
        RTf_EtEditFieldLabel            matlab.ui.control.Label
        RTf_EcEditField                 matlab.ui.control.NumericEditField
        RTf_EcEditFieldLabel            matlab.ui.control.Label
        RTf_PifnEditField               matlab.ui.control.NumericEditField
        RTf_PifnEditFieldLabel          matlab.ui.control.Label
        RTf_PinEditField                matlab.ui.control.NumericEditField
        RTf_PinEditFieldLabel           matlab.ui.control.Label
        RTf_PibEditField                matlab.ui.control.NumericEditField
        RTf_PibEditFieldLabel           matlab.ui.control.Label
        RTf_PidmaxEditField             matlab.ui.control.NumericEditField
        RTf_PidmaxEditFieldLabel        matlab.ui.control.Label
        RTf_PifEditField                matlab.ui.control.EditField
        RTf_PifEditFieldLabel           matlab.ui.control.Label
        RTf_PicEditField                matlab.ui.control.EditField
        RTf_PicEditFieldLabel           matlab.ui.control.Label
        RTf_BPREditField                matlab.ui.control.NumericEditField
        RTf_BPREditFieldLabel           matlab.ui.control.Label
        RTf_P0P19EditField              matlab.ui.control.NumericEditField
        RTf_P0P19EditFieldLabel         matlab.ui.control.Label
        RTf_P0P9EditField               matlab.ui.control.NumericEditField
        RTf_P0P9EditFieldLabel          matlab.ui.control.Label
        RTf_CptEditField                matlab.ui.control.NumericEditField
        RTf_CptEditFieldLabel           matlab.ui.control.Label
        RTf_GammaTEditField             matlab.ui.control.NumericEditField
        RTf_GammaTEditFieldLabel        matlab.ui.control.Label
        RTf_FlightRegimeGridCheckbox    matlab.ui.container.GridLayout
        RTf_TemperatureCheckbox         matlab.ui.control.CheckBox
        RTf_AltitudeCheckbox            matlab.ui.control.CheckBox
        RTf_FlightRegimeEditField       matlab.ui.control.EditField
        RTf_FlightRegimeEditFieldLabel  matlab.ui.control.Label
        RTf_Tt4EditField                matlab.ui.control.EditField
        RTf_Tt4EditFieldLabel           matlab.ui.control.Label
        RTf_HprEditField                matlab.ui.control.NumericEditField
        RTf_HprEditFieldLabel           matlab.ui.control.Label
        RTf_CpcEditField                matlab.ui.control.NumericEditField
        RTf_CpcEditFieldLabel           matlab.ui.control.Label
        RTf_GammaCEditField             matlab.ui.control.NumericEditField
        RTf_GammaCEditFieldLabel        matlab.ui.control.Label
        RTf_MachNumberEditField         matlab.ui.control.EditField
        RTf_MachNumberEditFieldLabel    matlab.ui.control.Label
        EngineSelectionPanel            matlab.ui.container.Panel
        EngineSelectionPanelGrid        matlab.ui.container.GridLayout
        SaveSelectionButton             matlab.ui.control.Button
        SelecionarTodosCheckbox         matlab.ui.control.CheckBox
        TurbofanRealCheckbox            matlab.ui.control.CheckBox
        TurbojetRealCheckbox            matlab.ui.control.CheckBox
        TurbopropIdealCheckbox          matlab.ui.control.CheckBox
        TurbofanIdealCheckbox           matlab.ui.control.CheckBox
        TurbojetIdealCheckbox           matlab.ui.control.CheckBox
        RamjetIdealCheckbox             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        minWidth = 800;             % Minimum app width
        minHeight = 600;            % Minimum app height
        maxOnePanelWidth = 1024;    % Maximum app width for 3x1 grid
        maxTwoPanelWidth = 1280;    % Maximum app width for 2x2 grid
    end

    properties (Access = public)
        selectedEngines             % Class for engine selection
        idealRamjetEngine           % Ideal Ramjet Engine class
        idealTurbojetEngine         % Ideal Turbojet Engine class
        idealTurbofanEngine         % Ideal Turbofan Engine class
        idealTurbopropEngine        % Ideal Turboprop Engine class
        realTurbojetEngine          % Real Turbojet Engine class
        realTurbofanEngine          % Real Turbofan Engine class
    end

    methods (Access = private)

        function UpdateAppLayout(app, ~)
            currentWidth = app.UIFigure.Position(3);

            if (currentWidth >= app.minWidth)
                if (currentWidth <= app.maxOnePanelWidth)
                    columns = 1;
                elseif (currentWidth > app.maxOnePanelWidth && currentWidth <= app.maxTwoPanelWidth)
                    columns = 2;
                elseif (currentWidth > app.maxTwoPanelWidth)
                    columns = 3;
                end
                app.UIFigure.Resize = true;
                UpdateAppGridLayout(app,columns);
                UpdateEngineSelectionPanelGrid(app, columns);
                UpdateEngineInputsRamjetIdealTabGrid(app, columns)
                UpdateEngineInputsTurbojetIdealTabGrid(app, columns);
                UpdateEngineInputsTurbofanIdealTabGrid(app, columns);
                UpdateEngineInputsTurobopropIdealTabGrid(app, columns);
                UpdateEngineInputsTurbojetRealTabGrid(app, columns);
                UpdateEngineInputsTurbofanRealTabGrid(app, columns);
            end
        end

        function FlightRegimeChange(~, changedCheckbox, otherCheckbox, editFieldLabel)
            value = changedCheckbox.Value;
            otherCheckbox.Value = ~value;

            if (value && strcmp(changedCheckbox.Text,'Temperatura'))
                editFieldLabel.Text = '$T_0 [K]$';
            elseif (~value && strcmp(changedCheckbox.Text,'Temperatura'))
                editFieldLabel.Text = '$h [m]$';
            elseif (value && strcmp(changedCheckbox.Text,'Altitude'))
                editFieldLabel.Text = '$h [m]$';
            elseif (~value && strcmp(changedCheckbox.Text,'Altitude'))
                editFieldLabel.Text = '$T_0 [K]$';
            end
        end

        function FlightRegimeChangePropagation(~, changedCheckbox, propagateCheckbox, otherCheckbox, editFieldLabel)
            value = changedCheckbox.Value;
            propagateCheckbox.Value = value;
            otherCheckbox.Value = ~value;

            if (value && strcmp(changedCheckbox.Text,'Temperatura'))
                editFieldLabel.Text = '$T_0 [K]$';
            elseif (~value && strcmp(changedCheckbox.Text,'Temperatura'))
                editFieldLabel.Text = '$h [m]$';
            elseif (value && strcmp(changedCheckbox.Text,'Altitude'))
                editFieldLabel.Text = '$h [m]$';
            elseif (~value && strcmp(changedCheckbox.Text,'Altitude'))
                editFieldLabel.Text = '$T_0 [K]$';
            end
        end

        function AfterburnerChange(~, afterburnerCheckbox, afterburnerField, afterburnerFieldLabel)
            value = afterburnerCheckbox.Value;
            afterburnerField.Enable = value;
            afterburnerFieldLabel.Enable = value;
        end

        function [selectedEngines] = SaveEngineSelection(app)
            idealRamjetSelected = app.RamjetIdealCheckbox.Value;
            idealTurbojetjetSelected = app.TurbojetIdealCheckbox.Value;
            realTurbojetSelected = app.TurbojetRealCheckbox.Value;
            idealTurbofanSelected = app.TurbofanIdealCheckbox.Value;
            realTurbofanSelected = app.TurbofanRealCheckbox.Value;
            idealTurbopropSelected = app.TurbopropIdealCheckbox.Value;

            selectedEngines = SelectedEngineType(idealRamjetSelected, ...
                idealTurbojetjetSelected, realTurbojetSelected,...
                idealTurbofanSelected, realTurbofanSelected,...
                idealTurbopropSelected);
        end

        function MachCopy(app, mach, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.IR_MachNumberEditField.Value = mach;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.ITj_MachNumberEditField.Value = mach;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.ITf_MachNumberEditField.Value = mach;
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.ITp_MachNumberEditField.Value = mach;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.RTj_MachNumberEditField.Value = mach;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_MachNumberEditField ~= engineFieldToCopyFrom)
                    app.RTf_MachNumberEditField.Value = mach;
                end
            end
        end

        function GammaCCopy(app, gammaC, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_GammaCEditField ~= engineFieldToCopyFrom)
                    app.IR_GammaCEditField.Value = gammaC;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_GammaCEditField ~= engineFieldToCopyFrom)
                    app.ITj_GammaCEditField.Value = gammaC;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_GammaCEditField ~= engineFieldToCopyFrom)
                    app.ITf_GammaCEditField.Value = gammaC;
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_GammaCEditField ~= engineFieldToCopyFrom)
                    app.ITp_GammaCEditField.Value = gammaC;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_GammaCEditField ~= engineFieldToCopyFrom)
                    app.RTj_GammaCEditField.Value = gammaC;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_GammaCEditField ~= engineFieldToCopyFrom)
                    app.RTf_GammaCEditField.Value = gammaC;
                end
            end
        end

        function CpCCopy(app, cpC, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_CpcEditField ~= engineFieldToCopyFrom)
                    app.IR_CpcEditField.Value = cpC;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_CpcEditField ~= engineFieldToCopyFrom)
                    app.ITj_CpcEditField.Value = cpC;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_CpcEditField ~= engineFieldToCopyFrom)
                    app.ITf_CpcEditField.Value = cpC;
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_CpcEditField ~= engineFieldToCopyFrom)
                    app.ITp_CpcEditField.Value = cpC;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_CpcEditField ~= engineFieldToCopyFrom)
                    app.RTj_CpcEditField.Value = cpC;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_CpcEditField ~= engineFieldToCopyFrom)
                    app.RTf_CpcEditField.Value = cpC;
                end
            end
        end

        function HprCopy(app, hpr, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_HprEditField ~= engineFieldToCopyFrom)
                    app.IR_HprEditField.Value = hpr;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_HprEditField ~= engineFieldToCopyFrom)
                    app.ITj_HprEditField.Value = hpr;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_HprEditField ~= engineFieldToCopyFrom)
                    app.ITf_HprEditField.Value = hpr;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_HprEditField ~= engineFieldToCopyFrom)
                    app.RTj_HprEditField.Value = hpr;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_HprEditField ~= engineFieldToCopyFrom)
                    app.RTf_HprEditField.Value = hpr;
                end
            end
        end

        function Tt4Copy(app, Tt4, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_Tt4EditField ~= engineFieldToCopyFrom)
                    app.IR_Tt4EditField.Value = Tt4;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_Tt4EditField ~= engineFieldToCopyFrom)
                    app.ITj_Tt4EditField.Value = Tt4;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_Tt4EditField ~= engineFieldToCopyFrom)
                    app.ITf_Tt4EditField.Value = Tt4;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_Tt4EditField ~= engineFieldToCopyFrom)
                    app.RTj_Tt4EditField.Value = Tt4;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_Tt4EditField ~= engineFieldToCopyFrom)
                    app.RTf_Tt4EditField.Value = Tt4;
                end
            end
        end

        function FlightRegimeCopy(app, FR, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.IR_FlightRegimeEditField.Value = FR;
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.ITj_FlightRegimeEditField.Value = FR;
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.ITf_FlightRegimeEditField.Value = FR;
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.ITp_FlightRegimeEditField.Value = FR;
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.RTj_FlightRegimeEditField.Value = FR;
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_FlightRegimeEditField ~= engineFieldToCopyFrom)
                    app.RTf_FlightRegimeEditField.Value = FR;
                end
            end
        end

        function FlightRegimeTemperatureCopy(app, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.IR_TemperatureCheckbox, app.IR_AltitudeCheckbox, app.IR_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITj_TemperatureCheckbox, app.ITj_AltitudeCheckbox, app.ITj_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITf_TemperatureCheckbox, app.ITf_AltitudeCheckbox, app.ITf_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITp_TemperatureCheckbox, app.ITp_AltitudeCheckbox, app.ITp_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.RTj_TemperatureCheckbox, app.RTj_AltitudeCheckbox, app.RTj_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.RTf_TemperatureCheckbox, app.RTf_AltitudeCheckbox, app.RTf_FlightRegimeEditFieldLabel);
                end
            end
        end

        function FlightRegimeAltitudeCopy(app, engineFieldToCopyFrom)
            if (app.ManterInputsComunsCheckbox.Value)
                if (app.selectedEngines.IdealRamjet && app.IR_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.IR_AltitudeCheckbox, app.IR_TemperatureCheckbox, app.IR_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurbojet && app.ITj_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITj_AltitudeCheckbox, app.ITj_TemperatureCheckbox, app.ITj_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurbofan && app.ITf_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITf_AltitudeCheckbox, app.ITf_TemperatureCheckbox, app.ITf_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.IdealTurboprop && app.ITp_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.ITp_AltitudeCheckbox, app.ITp_TemperatureCheckbox, app.ITp_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.RealTurbojet && app.RTj_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.RTj_AltitudeCheckbox, app.RTj_TemperatureCheckbox, app.RTj_FlightRegimeEditFieldLabel);
                end
                if (app.selectedEngines.RealTurbofan && app.RTf_TemperatureCheckbox ~= engineFieldToCopyFrom)
                    FlightRegimeChangePropagation(app, engineFieldToCopyFrom, app.RTf_AltitudeCheckbox, app.RTf_TemperatureCheckbox, app.RTf_FlightRegimeEditFieldLabel);
                end
            end
        end


        function AddDataToTable(app, newData, rowName)
            rowIndex = strcmpi(app.UITable.RowName, rowName);
            app.UITable.Data(rowIndex, :) = newData;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add folder to path
            addpath(genpath('./src'))

            % Set screen responsivity
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UpdateAppLayout, true);

            % Set initial app size based on user's resolution
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];

            % Set minimum app size
            webWindows = matlab.internal.webwindowmanager.instance.windowList;
            for i = 1:length(webWindows)
                if (strcmpi(webWindows(i).Title,'MATLAB App'))
                    webWindows(i).setMinSize([app.minWidth app.minHeight]);
                end
            end
            
            app.UITable.ColumnFormat = {'numeric' 'numeric' 'numeric' 'numeric' 'numeric' 'short e' 'short e' 'short e' 'short e' 'short e' 'short e'};
        end

        % Value changed function: SelecionarTodosCheckbox
        function SelecionarTodosCheckboxValueChanged(app, event)
            value = app.SelecionarTodosCheckbox.Value;
            app.RamjetIdealCheckbox.Value = value;
            app.TurbojetIdealCheckbox.Value = value;
            app.TurbofanIdealCheckbox.Value = value;
            app.TurbopropIdealCheckbox.Value = value;
            app.TurbojetRealCheckbox.Value = value;
            app.TurbofanRealCheckbox.Value = value;
        end

        % Value changed function: IR_TemperatureCheckbox
        function IR_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.IR_TemperatureCheckbox, app.IR_AltitudeCheckbox, app.IR_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.IR_TemperatureCheckbox);
        end

        % Value changed function: IR_AltitudeCheckbox
        function IR_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.IR_AltitudeCheckbox, app.IR_TemperatureCheckbox, app.IR_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.IR_AltitudeCheckbox);
        end

        % Value changed function: ITj_TemperatureCheckbox
        function ITj_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITj_TemperatureCheckbox, app.ITj_AltitudeCheckbox, app.ITj_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.ITj_TemperatureCheckbox);
        end

        % Value changed function: ITj_AltitudeCheckbox
        function ITj_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITj_AltitudeCheckbox, app.ITj_TemperatureCheckbox, app.ITj_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.ITj_AltitudeCheckbox);
        end

        % Value changed function: ITj_AfterburnerCheckbox
        function ITj_AfterburnerCheckboxValueChanged(app, event)
            AfterburnerChange(app, app.ITj_AfterburnerCheckbox, app.ITj_Tt7EditField, app.ITj_Tt7EditFieldLabel);
        end

        % Value changed function: ITf_TemperatureCheckbox
        function ITf_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITf_TemperatureCheckbox, app.ITf_AltitudeCheckbox, app.ITf_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.ITf_TemperatureCheckbox);
        end

        % Value changed function: ITf_AltitudeCheckbox
        function ITf_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITf_AltitudeCheckbox, app.ITf_TemperatureCheckbox, app.ITf_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.ITf_AltitudeCheckbox);
        end

        % Value changed function: ITp_TemperatureCheckbox
        function ITp_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITp_TemperatureCheckbox, app.ITp_AltitudeCheckbox, app.ITp_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.ITp_TemperatureCheckbox);
        end

        % Value changed function: ITp_AltitudeCheckbox
        function ITp_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.ITp_AltitudeCheckbox, app.ITp_TemperatureCheckbox, app.ITp_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.ITp_AltitudeCheckbox);
        end

        % Value changed function: ITp_ConventionalAdvancedButton
        function ITp_ConventionalAdvancedButtonValueChanged(app, event)
            value = app.ITp_ConventionalAdvancedButton.Value;

            if (value)
                app.ITp_ConventionalAdvancedButton.Text = 'Avançado';
                app.ITp_ConventionalAdvancedButton.BackgroundColor = [0 0.25 0.45];
                app.ITp_ConventionalAdvancedButton.FontColor = [1 1 1];
            else
                app.ITp_ConventionalAdvancedButton.Text = 'Convencional';
                app.ITp_ConventionalAdvancedButton.BackgroundColor = [0.97 0.97 1];
                app.ITp_ConventionalAdvancedButton.FontColor = [0 0 0];
            end
        end

        % Value changed function: RTj_TemperatureCheckbox
        function RTj_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.RTj_TemperatureCheckbox, app.RTj_AltitudeCheckbox, app.RTj_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.RTj_TemperatureCheckbox);
        end

        % Value changed function: RTj_AltitudeCheckbox
        function RTj_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.RTj_AltitudeCheckbox, app.RTj_TemperatureCheckbox, app.RTj_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.RTj_AltitudeCheckbox);
        end

        % Value changed function: RTj_AfterburnerCheckbox
        function RTj_AfterburnerCheckboxValueChanged(app, event)
            value = app.RTj_AfterburnerCheckbox.Value;

            app.RTj_Tt7EditFieldLabel.Enable = value;
            app.RTj_Tt7EditField.Enable = value;

            app.RTj_GammaABEditFieldLabel.Enable = value;
            app.RTj_GammaABEditField.Enable = value;

            app.RTj_CpabEditFieldLabel.Enable = value;
            app.RTj_CpabEditField.Enable = value;

            app.RTj_PiabEditFieldLabel.Enable = value;
            app.RTj_PiabEditField.Enable = value;

            app.RTj_EtaabEditFieldLabel.Enable = value;
            app.RTj_EtaabEditField.Enable = value;
        end

        % Value changed function: RTf_TemperatureCheckbox
        function RTf_TemperatureCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.RTf_TemperatureCheckbox, app.RTf_AltitudeCheckbox, app.RTf_FlightRegimeEditFieldLabel);
            FlightRegimeTemperatureCopy(app, app.RTf_TemperatureCheckbox);
        end

        % Value changed function: RTf_AltitudeCheckbox
        function RTf_AltitudeCheckboxValueChanged(app, event)
            FlightRegimeChange(app, app.RTf_AltitudeCheckbox, app.RTf_TemperatureCheckbox, app.RTf_FlightRegimeEditFieldLabel);
            FlightRegimeAltitudeCopy(app, app.RTf_AltitudeCheckbox);
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            [engines] = SaveEngineSelection(app);
            app.selectedEngines = engines;
            app.ManterInputsComunsCheckbox.Value = false;

            if (engines.CheckIfAnyIsSelected)
                % Enable/disable engine respective fields
                HandleEngineSelection(app)

                app.ManterInputsComunsCheckbox.Enable = true;
                app.InputsCOMPILARButton.Enable = true;
            else
                % Disable all engines respective fields
                HandleEngineSelection(app)
                app.ManterInputsComunsCheckbox.Enable = false;
                app.InputsCOMPILARButton.Enable = false;

                warndlg("Selecione um ou mais tipos de motor para análise",...
                    "Erro ao selecionar o(s) motor(es)")
            end
        end

        % Value changed function: ManterInputsComunsCheckbox
        function ManterInputsComunsCheckboxValueChanged(app, event)
            value = app.ManterInputsComunsCheckbox.Value;
            UseCommonInputs(app, value);
        end

        % Value changed function: IR_MachNumberEditField
        function IR_MachNumberEditFieldValueChanged(app, event)
            mach = app.IR_MachNumberEditField.Value;
            MachCopy(app, mach, app.IR_MachNumberEditField)
        end

        % Value changed function: IR_GammaCEditField
        function IR_GammaCEditFieldValueChanged(app, event)
            gammaC = app.IR_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.IR_GammaCEditField);
        end

        % Value changed function: IR_CpcEditField
        function IR_CpcEditFieldValueChanged(app, event)
            cpC = app.IR_CpcEditField.Value;
            CpCCopy(app, cpC, app.IR_CpcEditField);
        end

        % Value changed function: IR_HprEditField
        function IR_HprEditFieldValueChanged(app, event)
            hpr = app.IR_HprEditField.Value;
            HprCopy(app, hpr, app.IR_HprEditField);
        end

        % Value changed function: IR_Tt4EditField
        function IR_Tt4EditFieldValueChanged(app, event)
            Tt4 = app.IR_Tt4EditField.Value;
            Tt4Copy(app, Tt4, app.IR_Tt4EditField);
        end

        % Value changed function: IR_FlightRegimeEditField
        function IR_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.IR_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.IR_FlightRegimeEditField);
        end

        % Value changed function: ITj_MachNumberEditField
        function ITj_MachNumberEditFieldValueChanged(app, event)
            mach = app.ITj_MachNumberEditField.Value;
            MachCopy(app, mach, app.ITj_MachNumberEditField);
        end

        % Value changed function: ITj_GammaCEditField
        function ITj_GammaCEditFieldValueChanged(app, event)
            gammaC = app.ITj_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.ITj_GammaCEditField);
        end

        % Value changed function: ITj_CpcEditField
        function ITj_CpcEditFieldValueChanged(app, event)
            cpC = app.ITj_CpcEditField.Value;
            CpCCopy(app, cpC, app.ITj_CpcEditField);
        end

        % Value changed function: ITj_HprEditField
        function ITj_HprEditFieldValueChanged(app, event)
            hpr = app.ITj_HprEditField.Value;
            HprCopy(app, hpr, app.ITj_HprEditField);
        end

        % Value changed function: ITj_Tt4EditField
        function ITj_Tt4EditFieldValueChanged(app, event)
            Tt4 = app.ITj_Tt4EditField.Value;
            Tt4Copy(app, Tt4, app.ITj_Tt4EditField);
        end

        % Value changed function: ITj_FlightRegimeEditField
        function ITj_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.ITj_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.ITj_FlightRegimeEditField);
        end

        % Value changed function: ITf_MachNumberEditField
        function ITf_MachNumberEditFieldValueChanged(app, event)
            mach = app.ITf_MachNumberEditField.Value;
            MachCopy(app, mach, app.ITf_MachNumberEditField);
        end

        % Value changed function: ITf_GammaCEditField
        function ITf_GammaCEditFieldValueChanged(app, event)
            gammaC = app.ITf_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.ITf_GammaCEditField);
        end

        % Value changed function: ITf_CpcEditField
        function ITf_CpcEditFieldValueChanged(app, event)
            cpC = app.ITf_CpcEditField.Value;
            CpCCopy(app, cpC, app.ITj_CpcEditField);
        end

        % Value changed function: ITf_HprEditField
        function ITf_HprEditFieldValueChanged(app, event)
            hpr = app.ITf_HprEditField.Value;
            HprCopy(app, hpr, app.ITf_HprEditField);
        end

        % Value changed function: ITf_Tt4EditField
        function ITf_Tt4EditFieldValueChanged(app, event)
            Tt4 = app.ITf_Tt4EditField.Value;
            Tt4Copy(app, Tt4, app.ITf_Tt4EditField);
        end

        % Value changed function: ITf_FlightRegimeEditField
        function ITf_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.ITf_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.ITf_FlightRegimeEditField);
        end

        % Value changed function: ITp_MachNumberEditField
        function ITp_MachNumberEditFieldValueChanged(app, event)
            mach = app.ITp_MachNumberEditField.Value;
            MachCopy(app, mach, app.ITp_MachNumberEditField);
        end

        % Value changed function: ITp_FlightRegimeEditField
        function ITp_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.ITp_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.ITp_FlightRegimeEditField);
        end

        % Value changed function: ITp_GammaCEditField
        function ITp_GammaCEditFieldValueChanged(app, event)
            gammaC = app.ITp_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.ITp_GammaCEditField);
        end

        % Value changed function: ITp_CpcEditField
        function ITp_CpcEditFieldValueChanged(app, event)
            cpC = app.ITp_CpcEditField.Value;
            CpCCopy(app, cpC, app.ITp_CpcEditField);
        end

        % Value changed function: RTj_MachNumberEditField
        function RTj_MachNumberEditFieldValueChanged(app, event)
            mach = app.RTj_MachNumberEditField.Value;
            MachCopy(app, mach, app.RTj_MachNumberEditField);
        end

        % Value changed function: RTj_GammaCEditField
        function RTj_GammaCEditFieldValueChanged(app, event)
            gammaC = app.RTj_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.RTj_GammaCEditField);
        end

        % Value changed function: RTj_CpcEditField
        function RTj_CpcEditFieldValueChanged(app, event)
            cpC = app.RTj_CpcEditField.Value;
            CpCCopy(app, cpC, app.RTj_CpcEditField);
        end

        % Value changed function: RTj_HprEditField
        function RTj_HprEditFieldValueChanged(app, event)
            hpr = app.RTj_HprEditField.Value;
            HprCopy(app, hpr, app.RTj_HprEditField);
        end

        % Value changed function: RTj_Tt4EditField
        function RTj_Tt4EditFieldValueChanged(app, event)
            Tt4 = app.RTj_Tt4EditField.Value;
            Tt4Copy(app, Tt4, app.RTj_Tt4EditField);
        end

        % Value changed function: RTj_FlightRegimeEditField
        function RTj_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.RTj_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.RTj_FlightRegimeEditField);
        end

        % Value changed function: RTf_MachNumberEditField
        function RTf_MachNumberEditFieldValueChanged(app, event)
            mach = app.RTf_MachNumberEditField.Value;
            MachCopy(app, mach, app.RTf_MachNumberEditField);
        end

        % Value changed function: RTf_GammaCEditField
        function RTf_GammaCEditFieldValueChanged(app, event)
            gammaC = app.RTf_GammaCEditField.Value;
            GammaCCopy(app, gammaC, app.RTf_GammaCEditField);
        end

        % Value changed function: RTf_CpcEditField
        function RTf_CpcEditFieldValueChanged(app, event)
            cpC = app.RTf_CpcEditField.Value;
            CpCCopy(app, cpC, app.RTf_CpcEditField);
        end

        % Value changed function: RTf_HprEditField
        function RTf_HprEditFieldValueChanged(app, event)
            hpr = app.RTf_HprEditField.Value;
            HprCopy(app, hpr, app.RTf_HprEditField);
        end

        % Value changed function: RTf_Tt4EditField
        function RTf_Tt4EditFieldValueChanged(app, event)
            Tt4 = app.RTf_Tt4EditField.Value;
            Tt4Copy(app, Tt4, app.RTf_Tt4EditField);
        end

        % Value changed function: RTf_FlightRegimeEditField
        function RTf_FlightRegimeEditFieldValueChanged(app, event)
            FR_value = app.RTf_FlightRegimeEditField.Value;
            FlightRegimeCopy(app, FR_value, app.RTf_FlightRegimeEditField);
        end

        % Button pushed function: InputsCOMPILARButton
        function InputsCOMPILARButtonPushed(app, event)
            app.ExportTableButton.Enable = true;
            app.ExportTableButton.Visible = true;
            RunForSelectedEngines(app);
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            columns = horzcat('Engine',app.UITable.ColumnName');
            data = num2cell(app.UITable.Data,3);
            tableData = horzcat(app.UITable.RowName, data);
            cellExport = vertcat(columns, tableData);
            [fileName] = GenerateFilenameForExportTable('parametricCycleAnalysis.xlsx');
            writecell(cellExport,fileName)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EngineSelectionPanel = uipanel(app.GridLayout);
            app.EngineSelectionPanel.Tooltip = {'PASSO 1: SELEÇÃO DOS MOTORES'};
            app.EngineSelectionPanel.Title = 'PASSO 1: SELEÇÃO DOS MOTORES';
            app.EngineSelectionPanelGrid = uigridlayout(app.EngineSelectionPanel);
            app.RamjetIdealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.RamjetIdealCheckbox.Text = 'Ramjet Ideal';
            app.TurbojetIdealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.TurbojetIdealCheckbox.Text = 'Turbojet Ideal';
            app.TurbofanIdealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.TurbofanIdealCheckbox.Text = 'Turbofan Ideal';
            app.TurbopropIdealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.TurbopropIdealCheckbox.Text = 'Turboprop Ideal';
            app.TurbojetRealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.TurbojetRealCheckbox.Text = 'Turbojet Real';
            app.TurbofanRealCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.TurbofanRealCheckbox.Text = 'Turbofan Real';
            app.SelecionarTodosCheckbox = uicheckbox(app.EngineSelectionPanelGrid);
            app.SelecionarTodosCheckbox.ValueChangedFcn = createCallbackFcn(app, @SelecionarTodosCheckboxValueChanged, true);
            app.SelecionarTodosCheckbox.Text = 'Selecionar Todos';
            app.SaveSelectionButton = uibutton(app.EngineSelectionPanelGrid, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'SALVAR';
            app.EngineInputsPanel = uipanel(app.GridLayout);
            app.EngineInputsPanel.Tooltip = {'PASSO 2: INPUTS'};
            app.EngineInputsPanel.Title = 'PASSO 2: INPUTS ';
            app.EngineInputsPanelGrid = uigridlayout(app.EngineInputsPanel);
            app.EngineInputsPanelTabGroup = uitabgroup(app.EngineInputsPanelGrid);
            app.RamjetIdealTab = uitab(app.EngineInputsPanelTabGroup);
            app.RamjetIdealTab.Title = 'Ramjet Ideal';
            app.RamjetIdealTab.Tag = 'RamjetIdealTab';
            app.RamjetIdealTabGrid = uigridlayout(app.RamjetIdealTab);
            app.RamjetIdealTabGrid.Tag = 'RamjetIdealTabGrid';
            app.IR_MachNumberEditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_MachNumberEditFieldLabel.Tag = 'IR_MachNumberEditFieldLabel';
            app.IR_MachNumberEditFieldLabel.Enable = 'off';
            app.IR_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.IR_MachNumberEditField = uieditfield(app.RamjetIdealTabGrid, 'text');
            app.IR_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @IR_MachNumberEditFieldValueChanged, true);
            app.IR_MachNumberEditField.Tag = 'IR_MachNumberEditField';
            app.IR_MachNumberEditField.Enable = 'off';
            app.IR_GammaCEditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_GammaCEditFieldLabel.Interpreter = 'latex';
            app.IR_GammaCEditFieldLabel.Tag = 'IR_GammaCEditFieldLabel';
            app.IR_GammaCEditFieldLabel.Enable = 'off';
            app.IR_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.IR_GammaCEditField = uieditfield(app.RamjetIdealTabGrid, 'numeric');
            app.IR_GammaCEditField.Limits = [0 Inf];
            app.IR_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @IR_GammaCEditFieldValueChanged, true);
            app.IR_GammaCEditField.Tag = 'IR_GammaCEditField';
            app.IR_GammaCEditField.Enable = 'off';
            app.IR_CpcEditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_CpcEditFieldLabel.Interpreter = 'latex';
            app.IR_CpcEditFieldLabel.Tag = 'IR_CpcEditFieldLabel';
            app.IR_CpcEditFieldLabel.Enable = 'off';
            app.IR_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.IR_CpcEditField = uieditfield(app.RamjetIdealTabGrid, 'numeric');
            app.IR_CpcEditField.Limits = [0 Inf];
            app.IR_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @IR_CpcEditFieldValueChanged, true);
            app.IR_CpcEditField.Tag = 'IR_CpcEditField';
            app.IR_CpcEditField.Enable = 'off';
            app.IR_HprEditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_HprEditFieldLabel.Interpreter = 'latex';
            app.IR_HprEditFieldLabel.Tag = 'IR_HprEditFieldLabel';
            app.IR_HprEditFieldLabel.Enable = 'off';
            app.IR_HprEditFieldLabel.Text = '$h_{pr} \left[kJ/kg\right]$';
            app.IR_HprEditField = uieditfield(app.RamjetIdealTabGrid, 'numeric');
            app.IR_HprEditField.Limits = [0 Inf];
            app.IR_HprEditField.ValueChangedFcn = createCallbackFcn(app, @IR_HprEditFieldValueChanged, true);
            app.IR_HprEditField.Tag = 'IR_HprEditField';
            app.IR_HprEditField.Enable = 'off';
            app.IR_Tt4EditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_Tt4EditFieldLabel.Interpreter = 'latex';
            app.IR_Tt4EditFieldLabel.Tag = 'IR_Tt4EditFieldLabel';
            app.IR_Tt4EditFieldLabel.Enable = 'off';
            app.IR_Tt4EditFieldLabel.Text = '$T_{t4} [K]$';
            app.IR_Tt4EditField = uieditfield(app.RamjetIdealTabGrid, 'text');
            app.IR_Tt4EditField.ValueChangedFcn = createCallbackFcn(app, @IR_Tt4EditFieldValueChanged, true);
            app.IR_Tt4EditField.Tag = 'IR_Tt4EditField';
            app.IR_Tt4EditField.Enable = 'off';
            app.IR_FlightRegimeEditFieldLabel = uilabel(app.RamjetIdealTabGrid);
            app.IR_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.IR_FlightRegimeEditFieldLabel.Tag = 'IR_FlightRegimeEditFieldLabel';
            app.IR_FlightRegimeEditFieldLabel.Enable = 'off';
            app.IR_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.IR_FlightRegimeEditField = uieditfield(app.RamjetIdealTabGrid, 'text');
            app.IR_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @IR_FlightRegimeEditFieldValueChanged, true);
            app.IR_FlightRegimeEditField.Tag = 'IR_FlightRegimeEditField';
            app.IR_FlightRegimeEditField.Enable = 'off';
            app.IR_FlightRegimeGridCheckbox = uigridlayout(app.RamjetIdealTabGrid);
            app.IR_FlightRegimeGridCheckbox.Tag = 'IR_FlightRegimeGridCheckbox';
            app.IR_AltitudeCheckbox = uicheckbox(app.IR_FlightRegimeGridCheckbox);
            app.IR_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @IR_AltitudeCheckboxValueChanged, true);
            app.IR_AltitudeCheckbox.Tag = 'IR_AltitudeCheckBox';
            app.IR_AltitudeCheckbox.Enable = 'off';
            app.IR_AltitudeCheckbox.Text = 'Altitude';
            app.IR_TemperatureCheckbox = uicheckbox(app.IR_FlightRegimeGridCheckbox);
            app.IR_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @IR_TemperatureCheckboxValueChanged, true);
            app.IR_TemperatureCheckbox.Tag = 'IR_TemperatureCheckBox';
            app.IR_TemperatureCheckbox.Enable = 'off';
            app.IR_TemperatureCheckbox.Text = 'Temperatura';
            app.IR_TemperatureCheckbox.Value = true;
            app.TurbojetIdealTab = uitab(app.EngineInputsPanelTabGroup);
            app.TurbojetIdealTab.Title = 'Turbojet Ideal';
            app.TurbojetIdealTab.Tag = 'TurbojetIdealTab';
            app.TurbojetIdealTabGrid = uigridlayout(app.TurbojetIdealTab);
            app.TurbojetIdealTabGrid.Tag = 'TurbojetIdealTabGrid';
            app.ITj_MachNumberEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_MachNumberEditFieldLabel.Tag = 'ITj_MachNumberEditFieldLabel';
            app.ITj_MachNumberEditFieldLabel.Enable = 'off';
            app.ITj_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.ITj_MachNumberEditField = uieditfield(app.TurbojetIdealTabGrid, 'text');
            app.ITj_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ITj_MachNumberEditFieldValueChanged, true);
            app.ITj_MachNumberEditField.Tag = 'ITj_MachNumberEditField';
            app.ITj_MachNumberEditField.Enable = 'off';
            app.ITj_GammaCEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_GammaCEditFieldLabel.Interpreter = 'latex';
            app.ITj_GammaCEditFieldLabel.Tag = 'ITj_GammaCEditFieldLabel';
            app.ITj_GammaCEditFieldLabel.Enable = 'off';
            app.ITj_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.ITj_GammaCEditField = uieditfield(app.TurbojetIdealTabGrid, 'numeric');
            app.ITj_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @ITj_GammaCEditFieldValueChanged, true);
            app.ITj_GammaCEditField.Tag = 'ITj_GammaCEditField';
            app.ITj_GammaCEditField.Enable = 'off';
            app.ITj_CpcEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_CpcEditFieldLabel.Interpreter = 'latex';
            app.ITj_CpcEditFieldLabel.Tag = 'ITj_CpcEditFieldLabel';
            app.ITj_CpcEditFieldLabel.Enable = 'off';
            app.ITj_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.ITj_CpcEditField = uieditfield(app.TurbojetIdealTabGrid, 'numeric');
            app.ITj_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @ITj_CpcEditFieldValueChanged, true);
            app.ITj_CpcEditField.Tag = 'ITj_CpcEditField';
            app.ITj_CpcEditField.Enable = 'off';
            app.ITj_HprEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_HprEditFieldLabel.Interpreter = 'latex';
            app.ITj_HprEditFieldLabel.Tag = 'ITj_HprEditFieldLabel';
            app.ITj_HprEditFieldLabel.Enable = 'off';
            app.ITj_HprEditFieldLabel.Text = '$h_{pr} \left[kJ/kg\right]$';
            app.ITj_HprEditField = uieditfield(app.TurbojetIdealTabGrid, 'numeric');
            app.ITj_HprEditField.ValueChangedFcn = createCallbackFcn(app, @ITj_HprEditFieldValueChanged, true);
            app.ITj_HprEditField.Tag = 'ITj_HprEditField';
            app.ITj_HprEditField.Enable = 'off';
            app.ITj_Tt4EditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_Tt4EditFieldLabel.Interpreter = 'latex';
            app.ITj_Tt4EditFieldLabel.Tag = 'ITj_Tt4EditFieldLabel';
            app.ITj_Tt4EditFieldLabel.Enable = 'off';
            app.ITj_Tt4EditFieldLabel.Text = '$T_{t4} [K]$';
            app.ITj_Tt4EditField = uieditfield(app.TurbojetIdealTabGrid, 'text');
            app.ITj_Tt4EditField.ValueChangedFcn = createCallbackFcn(app, @ITj_Tt4EditFieldValueChanged, true);
            app.ITj_Tt4EditField.Tag = 'ITj_Tt4EditField';
            app.ITj_Tt4EditField.Enable = 'off';
            app.ITj_FlightRegimeEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.ITj_FlightRegimeEditFieldLabel.Tag = 'ITj_FlightRegimeEditFieldLabel';
            app.ITj_FlightRegimeEditFieldLabel.Enable = 'off';
            app.ITj_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.ITj_FlightRegimeEditField = uieditfield(app.TurbojetIdealTabGrid, 'text');
            app.ITj_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @ITj_FlightRegimeEditFieldValueChanged, true);
            app.ITj_FlightRegimeEditField.Tag = 'ITj_FlightRegimeEditField';
            app.ITj_FlightRegimeEditField.Enable = 'off';
            app.ITj_FlightRegimeGridCheckbox = uigridlayout(app.TurbojetIdealTabGrid);
            app.ITj_FlightRegimeGridCheckbox.Tag = 'ITj_FlightRegimeGridCheckbox';
            app.ITj_AltitudeCheckbox = uicheckbox(app.ITj_FlightRegimeGridCheckbox);
            app.ITj_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITj_AltitudeCheckboxValueChanged, true);
            app.ITj_AltitudeCheckbox.Tag = 'ITj_AltitudeCheckbox';
            app.ITj_AltitudeCheckbox.Enable = 'off';
            app.ITj_AltitudeCheckbox.Text = 'Altitude';
            app.ITj_TemperatureCheckbox = uicheckbox(app.ITj_FlightRegimeGridCheckbox);
            app.ITj_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITj_TemperatureCheckboxValueChanged, true);
            app.ITj_TemperatureCheckbox.Tag = 'ITj_TemperatureCheckbox';
            app.ITj_TemperatureCheckbox.Enable = 'off';
            app.ITj_TemperatureCheckbox.Text = 'Temperatura';
            app.ITj_TemperatureCheckbox.Value = true;
            app.ITj_PiCEditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_PiCEditFieldLabel.Interpreter = 'latex';
            app.ITj_PiCEditFieldLabel.Tag = 'ITj_PiCEditFieldLabel';
            app.ITj_PiCEditFieldLabel.Enable = 'off';
            app.ITj_PiCEditFieldLabel.Text = '$\pi_C$';
            app.ITj_PiCEditField = uieditfield(app.TurbojetIdealTabGrid, 'numeric');
            app.ITj_PiCEditField.Tag = 'ITj_PiCEditField';
            app.ITj_PiCEditField.Enable = 'off';
            app.ITj_Tt7EditFieldLabel = uilabel(app.TurbojetIdealTabGrid);
            app.ITj_Tt7EditFieldLabel.Interpreter = 'latex';
            app.ITj_Tt7EditFieldLabel.Tag = 'ITj_Tt7EditFieldLabel';
            app.ITj_Tt7EditFieldLabel.Enable = 'off';
            app.ITj_Tt7EditFieldLabel.Text = '$T_{t7} [K]$';
            app.ITj_Tt7EditField = uieditfield(app.TurbojetIdealTabGrid, 'numeric');
            app.ITj_Tt7EditField.Tag = 'ITj_Tt7EditField';
            app.ITj_Tt7EditField.Enable = 'off';
            app.ITj_AfterburnerCheckbox = uicheckbox(app.TurbojetIdealTabGrid);
            app.ITj_AfterburnerCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITj_AfterburnerCheckboxValueChanged, true);
            app.ITj_AfterburnerCheckbox.Tag = 'ITj_AfterburnerCheckbox';
            app.ITj_AfterburnerCheckbox.Enable = 'off';
            app.ITj_AfterburnerCheckbox.Text = 'Pós-combustor';
            app.ITj_AfterburnerCheckbox.Value = true;
            app.TurbofanIdealTab = uitab(app.EngineInputsPanelTabGroup);
            app.TurbofanIdealTab.Title = 'Turbofan Ideal';
            app.TurbofanIdealTab.Tag = 'TurbofanIdealTab';
            app.TurbofanIdealTabGrid = uigridlayout(app.TurbofanIdealTab);
            app.TurbofanIdealTabGrid.Tag = 'TurbofanIdealTabGrid';
            app.ITf_MachNumberEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_MachNumberEditFieldLabel.Tag = 'ITf_MachNumberEditFieldLabel';
            app.ITf_MachNumberEditFieldLabel.Enable = 'off';
            app.ITf_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.ITf_MachNumberEditField = uieditfield(app.TurbofanIdealTabGrid, 'text');
            app.ITf_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ITf_MachNumberEditFieldValueChanged, true);
            app.ITf_MachNumberEditField.Tag = 'ITf_MachNumberEditField';
            app.ITf_MachNumberEditField.Enable = 'off';
            app.ITf_GammaCEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_GammaCEditFieldLabel.Interpreter = 'latex';
            app.ITf_GammaCEditFieldLabel.Tag = 'ITf_GammaCEditFieldLabel';
            app.ITf_GammaCEditFieldLabel.Enable = 'off';
            app.ITf_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.ITf_GammaCEditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @ITf_GammaCEditFieldValueChanged, true);
            app.ITf_GammaCEditField.Tag = 'ITf_GammaCEditField';
            app.ITf_GammaCEditField.Enable = 'off';
            app.ITf_CpcEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_CpcEditFieldLabel.Interpreter = 'latex';
            app.ITf_CpcEditFieldLabel.Tag = 'ITf_CpcEditFieldLabel';
            app.ITf_CpcEditFieldLabel.Enable = 'off';
            app.ITf_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.ITf_CpcEditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @ITf_CpcEditFieldValueChanged, true);
            app.ITf_CpcEditField.Tag = 'ITf_CpcEditField';
            app.ITf_CpcEditField.Enable = 'off';
            app.ITf_HprEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_HprEditFieldLabel.Interpreter = 'latex';
            app.ITf_HprEditFieldLabel.Tag = 'ITf_HprEditFieldLabel';
            app.ITf_HprEditFieldLabel.Enable = 'off';
            app.ITf_HprEditFieldLabel.Text = '$h_{pr} \left[kJ/kg\right]$';
            app.ITf_HprEditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_HprEditField.ValueChangedFcn = createCallbackFcn(app, @ITf_HprEditFieldValueChanged, true);
            app.ITf_HprEditField.Tag = 'ITf_HprEditField';
            app.ITf_HprEditField.Enable = 'off';
            app.ITf_Tt4EditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_Tt4EditFieldLabel.Interpreter = 'latex';
            app.ITf_Tt4EditFieldLabel.Tag = 'ITf_Tt4EditFieldLabel';
            app.ITf_Tt4EditFieldLabel.Enable = 'off';
            app.ITf_Tt4EditFieldLabel.Text = '$T_{t4} [K]$';
            app.ITf_Tt4EditField = uieditfield(app.TurbofanIdealTabGrid, 'text');
            app.ITf_Tt4EditField.ValueChangedFcn = createCallbackFcn(app, @ITf_Tt4EditFieldValueChanged, true);
            app.ITf_Tt4EditField.Tag = 'ITf_Tt4EditField';
            app.ITf_Tt4EditField.Enable = 'off';
            app.ITf_FlightRegimeEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.ITf_FlightRegimeEditFieldLabel.Tag = 'ITf_FlightRegimeEditFieldLabel';
            app.ITf_FlightRegimeEditFieldLabel.Enable = 'off';
            app.ITf_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.ITf_FlightRegimeEditField = uieditfield(app.TurbofanIdealTabGrid, 'text');
            app.ITf_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @ITf_FlightRegimeEditFieldValueChanged, true);
            app.ITf_FlightRegimeEditField.Tag = 'ITf_FlightRegimeEditField';
            app.ITf_FlightRegimeEditField.Enable = 'off';
            app.ITf_FlightRegimeGridCheckbox = uigridlayout(app.TurbofanIdealTabGrid);
            app.ITf_FlightRegimeGridCheckbox.Tag = 'ITf_FlightRegimeGridCheckbox';
            app.ITf_TemperatureCheckbox = uicheckbox(app.ITf_FlightRegimeGridCheckbox);
            app.ITf_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITf_TemperatureCheckboxValueChanged, true);
            app.ITf_TemperatureCheckbox.Tag = 'ITf_TemperatureCheckbox';
            app.ITf_TemperatureCheckbox.Enable = 'off';
            app.ITf_TemperatureCheckbox.Text = 'Temperatura';
            app.ITf_TemperatureCheckbox.Value = true;
            app.ITf_AltitudeCheckbox = uicheckbox(app.ITf_FlightRegimeGridCheckbox);
            app.ITf_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITf_AltitudeCheckboxValueChanged, true);
            app.ITf_AltitudeCheckbox.Tag = 'ITf_AltitudeCheckbox';
            app.ITf_AltitudeCheckbox.Enable = 'off';
            app.ITf_AltitudeCheckbox.Text = 'Altitude';
            app.ITf_PicEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_PicEditFieldLabel.Interpreter = 'latex';
            app.ITf_PicEditFieldLabel.Tag = 'ITf_PicEditFieldLabel';
            app.ITf_PicEditFieldLabel.Enable = 'off';
            app.ITf_PicEditFieldLabel.Text = '$\pi_C$';
            app.ITf_PicEditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_PicEditField.Tag = 'ITf_PicEditField';
            app.ITf_PicEditField.Enable = 'off';
            app.ITf_PifEditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_PifEditFieldLabel.Interpreter = 'latex';
            app.ITf_PifEditFieldLabel.Tag = 'ITf_PifEditFieldLabel';
            app.ITf_PifEditFieldLabel.Enable = 'off';
            app.ITf_PifEditFieldLabel.Text = '$\pi_F$';
            app.ITf_PifEditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_PifEditField.Tag = 'ITf_PifEditField';
            app.ITf_PifEditField.Enable = 'off';
            app.ITf_BPREditFieldLabel = uilabel(app.TurbofanIdealTabGrid);
            app.ITf_BPREditFieldLabel.Tag = 'ITf_BPREditFieldLabel';
            app.ITf_BPREditFieldLabel.Enable = 'off';
            app.ITf_BPREditFieldLabel.Text = 'BPR';
            app.ITf_BPREditField = uieditfield(app.TurbofanIdealTabGrid, 'numeric');
            app.ITf_BPREditField.Tag = 'ITf_BPREditField';
            app.ITf_BPREditField.Enable = 'off';
            app.TurbopropIdealTab = uitab(app.EngineInputsPanelTabGroup);
            app.TurbopropIdealTab.Title = 'Turboprop Ideal';
            app.TurbopropIdealTab.Tag = 'TurbopropIdealTab';
            app.TurbopropIdealTabGrid = uigridlayout(app.TurbopropIdealTab);
            app.TurbopropIdealTabGrid.Tag = 'TurbopropIdealTabGrid';
            app.ITp_MachNumberEditFieldLabel = uilabel(app.TurbopropIdealTabGrid);
            app.ITp_MachNumberEditFieldLabel.Tag = 'ITp_MachNumberEditFieldLabel';
            app.ITp_MachNumberEditFieldLabel.Enable = 'off';
            app.ITp_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.ITp_MachNumberEditField = uieditfield(app.TurbopropIdealTabGrid, 'text');
            app.ITp_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ITp_MachNumberEditFieldValueChanged, true);
            app.ITp_MachNumberEditField.Tag = 'ITp_MachNumberEditField';
            app.ITp_MachNumberEditField.Enable = 'off';
            app.ITp_FlightRegimeEditFieldLabel = uilabel(app.TurbopropIdealTabGrid);
            app.ITp_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.ITp_FlightRegimeEditFieldLabel.Tag = 'ITp_FlightRegimeEditFieldLabel';
            app.ITp_FlightRegimeEditFieldLabel.Enable = 'off';
            app.ITp_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.ITp_GammaCEditFieldLabel = uilabel(app.TurbopropIdealTabGrid);
            app.ITp_GammaCEditFieldLabel.Interpreter = 'latex';
            app.ITp_GammaCEditFieldLabel.Enable = 'off';
            app.ITp_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.ITp_GammaCEditField = uieditfield(app.TurbopropIdealTabGrid, 'numeric');
            app.ITp_GammaCEditField.Limits = [0 Inf];
            app.ITp_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @ITp_GammaCEditFieldValueChanged, true);
            app.ITp_GammaCEditField.Enable = 'off';
            app.ITp_CpcEditFieldLabel = uilabel(app.TurbopropIdealTabGrid);
            app.ITp_CpcEditFieldLabel.Interpreter = 'latex';
            app.ITp_CpcEditFieldLabel.Enable = 'off';
            app.ITp_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.ITp_CpcEditField = uieditfield(app.TurbopropIdealTabGrid, 'numeric');
            app.ITp_CpcEditField.Limits = [0 Inf];
            app.ITp_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @ITp_CpcEditFieldValueChanged, true);
            app.ITp_CpcEditField.Enable = 'off';
            app.ITp_FlightRegimeEditField = uieditfield(app.TurbopropIdealTabGrid, 'text');
            app.ITp_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @ITp_FlightRegimeEditFieldValueChanged, true);
            app.ITp_FlightRegimeEditField.Tag = 'ITp_FlightRegimeEditFieldLabel';
            app.ITp_FlightRegimeEditField.Enable = 'off';
            app.ITp_FlightRegimeGridCheckbox = uigridlayout(app.TurbopropIdealTabGrid);
            app.ITp_FlightRegimeGridCheckbox.Tag = 'ITp_FlightRegimeGridCheckbox';
            app.ITp_TemperatureCheckbox = uicheckbox(app.ITp_FlightRegimeGridCheckbox);
            app.ITp_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITp_TemperatureCheckboxValueChanged, true);
            app.ITp_TemperatureCheckbox.Tag = 'ITp_TemperatureCheckbox';
            app.ITp_TemperatureCheckbox.Enable = 'off';
            app.ITp_TemperatureCheckbox.Text = 'Temperatura';
            app.ITp_TemperatureCheckbox.Value = true;
            app.ITp_AltitudeCheckbox = uicheckbox(app.ITp_FlightRegimeGridCheckbox);
            app.ITp_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @ITp_AltitudeCheckboxValueChanged, true);
            app.ITp_AltitudeCheckbox.Tag = 'ITp_AltitudeCheckbox';
            app.ITp_AltitudeCheckbox.Enable = 'off';
            app.ITp_AltitudeCheckbox.Text = 'Altitude';
            app.ITp_ConventionalAdvancedButton = uibutton(app.TurbopropIdealTabGrid, 'state');
            app.ITp_ConventionalAdvancedButton.ValueChangedFcn = createCallbackFcn(app, @ITp_ConventionalAdvancedButtonValueChanged, true);
            app.ITp_ConventionalAdvancedButton.Tag = 'ITp_ConventionalAdvancedButton';
            app.ITp_ConventionalAdvancedButton.Enable = 'off';
            app.ITp_ConventionalAdvancedButton.Text = 'Convencional';
            app.TurbojetRealTab = uitab(app.EngineInputsPanelTabGroup);
            app.TurbojetRealTab.Title = 'Turbojet Real';
            app.TurbojetRealTab.Tag = 'TurbojetRealTab';
            app.TurbojetRealTabGrid = uigridlayout(app.TurbojetRealTab);
            app.TurbojetRealTabGrid.Tag = 'TurbojetRealTabGrid';
            app.RTj_MachNumberEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_MachNumberEditFieldLabel.Tag = 'RTj_MachNumberEditFieldLabel';
            app.RTj_MachNumberEditFieldLabel.Enable = 'off';
            app.RTj_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.RTj_MachNumberEditField = uieditfield(app.TurbojetRealTabGrid, 'text');
            app.RTj_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @RTj_MachNumberEditFieldValueChanged, true);
            app.RTj_MachNumberEditField.Tag = 'RTj_MachNumberEditField';
            app.RTj_MachNumberEditField.Enable = 'off';
            app.RTj_GammaCEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_GammaCEditFieldLabel.Interpreter = 'latex';
            app.RTj_GammaCEditFieldLabel.Tag = 'RTj_GammaCEditFieldLabel';
            app.RTj_GammaCEditFieldLabel.Enable = 'off';
            app.RTj_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.RTj_GammaCEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @RTj_GammaCEditFieldValueChanged, true);
            app.RTj_GammaCEditField.Tag = 'RTj_GammaCEditField';
            app.RTj_GammaCEditField.Enable = 'off';
            app.RTj_CpcEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_CpcEditFieldLabel.Interpreter = 'latex';
            app.RTj_CpcEditFieldLabel.Tag = 'RTj_CpcEditFieldLabel';
            app.RTj_CpcEditFieldLabel.Enable = 'off';
            app.RTj_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.RTj_CpcEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @RTj_CpcEditFieldValueChanged, true);
            app.RTj_CpcEditField.Tag = 'RTj_CpcEditField';
            app.RTj_CpcEditField.Enable = 'off';
            app.RTj_HprEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_HprEditFieldLabel.Interpreter = 'latex';
            app.RTj_HprEditFieldLabel.Tag = 'RTj_HprEditFieldLabel';
            app.RTj_HprEditFieldLabel.Enable = 'off';
            app.RTj_HprEditFieldLabel.Text = '$h_{pr} \left[kJ/kg\right]$';
            app.RTj_HprEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_HprEditField.ValueChangedFcn = createCallbackFcn(app, @RTj_HprEditFieldValueChanged, true);
            app.RTj_HprEditField.Tag = 'RTj_HprEditField';
            app.RTj_HprEditField.Enable = 'off';
            app.RTj_Tt4EditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_Tt4EditFieldLabel.Interpreter = 'latex';
            app.RTj_Tt4EditFieldLabel.Tag = 'RTj_Tt4EditFieldLabel';
            app.RTj_Tt4EditFieldLabel.Enable = 'off';
            app.RTj_Tt4EditFieldLabel.Text = '$T_{t4} [K]$';
            app.RTj_Tt4EditField = uieditfield(app.TurbojetRealTabGrid, 'text');
            app.RTj_Tt4EditField.ValueChangedFcn = createCallbackFcn(app, @RTj_Tt4EditFieldValueChanged, true);
            app.RTj_Tt4EditField.Tag = 'RTj_Tt4EditField';
            app.RTj_Tt4EditField.Enable = 'off';
            app.RTj_FlightRegimeEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.RTj_FlightRegimeEditFieldLabel.Tag = 'RTj_FlightRegimeEditFieldLabel';
            app.RTj_FlightRegimeEditFieldLabel.Enable = 'off';
            app.RTj_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.RTj_FlightRegimeEditField = uieditfield(app.TurbojetRealTabGrid, 'text');
            app.RTj_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @RTj_FlightRegimeEditFieldValueChanged, true);
            app.RTj_FlightRegimeEditField.Enable = 'off';
            app.RTj_FlightRegimeGridCheckbox = uigridlayout(app.TurbojetRealTabGrid);
            app.RTj_FlightRegimeGridCheckbox.Tag = 'RTj_FlightRegimeGridCheckbox';
            app.RTj_AltitudeCheckbox = uicheckbox(app.RTj_FlightRegimeGridCheckbox);
            app.RTj_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @RTj_AltitudeCheckboxValueChanged, true);
            app.RTj_AltitudeCheckbox.Tag = 'RTj_AltitudeCheckbox';
            app.RTj_AltitudeCheckbox.Enable = 'off';
            app.RTj_AltitudeCheckbox.Text = 'Altitude';
            app.RTj_TemperatureCheckbox = uicheckbox(app.RTj_FlightRegimeGridCheckbox);
            app.RTj_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @RTj_TemperatureCheckboxValueChanged, true);
            app.RTj_TemperatureCheckbox.Tag = 'RTj_TemperatureCheckbox';
            app.RTj_TemperatureCheckbox.Enable = 'off';
            app.RTj_TemperatureCheckbox.Text = 'Temperatura';
            app.RTj_TemperatureCheckbox.Value = true;
            app.RTj_GammaTEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_GammaTEditFieldLabel.Interpreter = 'latex';
            app.RTj_GammaTEditFieldLabel.Tag = 'RTj_GammaTEditFieldLabel';
            app.RTj_GammaTEditFieldLabel.Enable = 'off';
            app.RTj_GammaTEditFieldLabel.Text = '$\gamma_T$';
            app.RTj_GammaTEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_GammaTEditField.Tag = 'RTj_GammaTEditField';
            app.RTj_GammaTEditField.Enable = 'off';
            app.RTj_CptEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_CptEditFieldLabel.Interpreter = 'latex';
            app.RTj_CptEditFieldLabel.Tag = 'RTj_CptEditFieldLabel';
            app.RTj_CptEditFieldLabel.Enable = 'off';
            app.RTj_CptEditFieldLabel.Text = '$c_{pt} \left[kJ /kg K\right]$';
            app.RTj_CptEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_CptEditField.Tag = 'RTj_CptEditField';
            app.RTj_CptEditField.Enable = 'off';
            app.RTj_P0P9EditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_P0P9EditFieldLabel.Interpreter = 'latex';
            app.RTj_P0P9EditFieldLabel.Tag = 'RTj_P0P9EditFieldLabel';
            app.RTj_P0P9EditFieldLabel.Enable = 'off';
            app.RTj_P0P9EditFieldLabel.Text = '$P_0/P_9$';
            app.RTj_P0P9EditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_P0P9EditField.Tag = 'RTj_P0P9EditField';
            app.RTj_P0P9EditField.Enable = 'off';
            app.RTj_PicEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_PicEditFieldLabel.Interpreter = 'latex';
            app.RTj_PicEditFieldLabel.Tag = 'RTj_PicEditFieldLabel';
            app.RTj_PicEditFieldLabel.Enable = 'off';
            app.RTj_PicEditFieldLabel.Text = '$\pi_C$';
            app.RTj_PicEditField = uieditfield(app.TurbojetRealTabGrid, 'text');
            app.RTj_PicEditField.Tag = 'RTj_PicEditField';
            app.RTj_PicEditField.Enable = 'off';
            app.RTj_PidmaxEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_PidmaxEditFieldLabel.Interpreter = 'latex';
            app.RTj_PidmaxEditFieldLabel.Tag = 'RTj_PidmaxEditFieldLabel';
            app.RTj_PidmaxEditFieldLabel.Enable = 'off';
            app.RTj_PidmaxEditFieldLabel.Text = '$\pi_{D max}$';
            app.RTj_PidmaxEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_PidmaxEditField.Tag = 'RTj_PidmaxEditField';
            app.RTj_PidmaxEditField.Enable = 'off';
            app.RTj_PibEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_PibEditFieldLabel.Interpreter = 'latex';
            app.RTj_PibEditFieldLabel.Tag = 'RTj_PibEditFieldLabel';
            app.RTj_PibEditFieldLabel.Enable = 'off';
            app.RTj_PibEditFieldLabel.Text = '$\pi_{B}$';
            app.RTj_PibEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_PibEditField.Tag = 'RTj_PibEditField';
            app.RTj_PibEditField.Enable = 'off';
            app.RTj_PinEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_PinEditFieldLabel.Interpreter = 'latex';
            app.RTj_PinEditFieldLabel.Tag = 'RTj_PinEditFieldLabel';
            app.RTj_PinEditFieldLabel.Enable = 'off';
            app.RTj_PinEditFieldLabel.Text = '$\pi_{N}$';
            app.RTj_PinEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_PinEditField.Tag = 'RTj_PinEditField';
            app.RTj_PinEditField.Enable = 'off';
            app.RTj_EcEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_EcEditFieldLabel.Interpreter = 'latex';
            app.RTj_EcEditFieldLabel.Tag = 'RTj_EcEditFieldLabel';
            app.RTj_EcEditFieldLabel.Enable = 'off';
            app.RTj_EcEditFieldLabel.Text = '$e_{C}$';
            app.RTj_EcEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_EcEditField.Tag = 'RTj_EcEditField';
            app.RTj_EcEditField.Enable = 'off';
            app.RTj_EtEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_EtEditFieldLabel.Interpreter = 'latex';
            app.RTj_EtEditFieldLabel.Tag = 'RTj_EtEditFieldLabel';
            app.RTj_EtEditFieldLabel.Enable = 'off';
            app.RTj_EtEditFieldLabel.Text = '$e_{T}$';
            app.RTj_EtEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_EtEditField.Tag = 'RTj_EtEditField';
            app.RTj_EtEditField.Enable = 'off';
            app.RTj_EtabEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_EtabEditFieldLabel.Interpreter = 'latex';
            app.RTj_EtabEditFieldLabel.Tag = 'RTj_EtabEditFieldLabel';
            app.RTj_EtabEditFieldLabel.Enable = 'off';
            app.RTj_EtabEditFieldLabel.Text = '$\eta_{B}$';
            app.RTj_EtabEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_EtabEditField.Tag = 'RTj_EtabEditField';
            app.RTj_EtabEditField.Enable = 'off';
            app.RTj_EtamEditFieldLabel = uilabel(app.TurbojetRealTabGrid);
            app.RTj_EtamEditFieldLabel.Interpreter = 'latex';
            app.RTj_EtamEditFieldLabel.Tag = 'RTj_EtamEditFieldLabel';
            app.RTj_EtamEditFieldLabel.Enable = 'off';
            app.RTj_EtamEditFieldLabel.Text = '$\eta_{M}$';
            app.RTj_EtamEditField = uieditfield(app.TurbojetRealTabGrid, 'numeric');
            app.RTj_EtamEditField.Tag = 'RTj_EtamEditField';
            app.RTj_EtamEditField.Enable = 'off';
            app.RTj_AfterburnerPanel = uipanel(app.TurbojetRealTabGrid);
            app.RTj_AfterburnerPanel.Title = 'Pós-combustor';
            app.RTj_AfterburnerPanel.Tag = 'RTj_AfterburnerPanel';
            app.RTj_AfterburnerPanelGrid = uigridlayout(app.RTj_AfterburnerPanel);
            app.RTj_AfterburnerPanelGrid.Tag = 'RTj_AfterburnerPanelGrid';
            app.RTj_AfterburnerCheckbox = uicheckbox(app.RTj_AfterburnerPanelGrid);
            app.RTj_AfterburnerCheckbox.ValueChangedFcn = createCallbackFcn(app, @RTj_AfterburnerCheckboxValueChanged, true);
            app.RTj_AfterburnerCheckbox.Tag = 'RTj_AfterburnerCheckbox';
            app.RTj_AfterburnerCheckbox.Enable = 'off';
            app.RTj_AfterburnerCheckbox.Text = 'Pós-combustor';
            app.RTj_AfterburnerCheckbox.Value = true;
            app.RTj_Tt7EditFieldLabel = uilabel(app.RTj_AfterburnerPanelGrid);
            app.RTj_Tt7EditFieldLabel.Interpreter = 'latex';
            app.RTj_Tt7EditFieldLabel.Tag = 'RTj_Tt7EditFieldLabel';
            app.RTj_Tt7EditFieldLabel.Enable = 'off';
            app.RTj_Tt7EditFieldLabel.Text = '$T_{t7} [K]$';
            app.RTj_Tt7EditField = uieditfield(app.RTj_AfterburnerPanelGrid, 'numeric');
            app.RTj_Tt7EditField.Tag = 'RTj_Tt7EditField';
            app.RTj_Tt7EditField.Enable = 'off';
            app.RTj_GammaABEditFieldLabel = uilabel(app.RTj_AfterburnerPanelGrid);
            app.RTj_GammaABEditFieldLabel.Interpreter = 'latex';
            app.RTj_GammaABEditFieldLabel.Tag = 'RTj_GammaABEditFieldLabel';
            app.RTj_GammaABEditFieldLabel.Enable = 'off';
            app.RTj_GammaABEditFieldLabel.Text = '$\gamma_{AB}$';
            app.RTj_GammaABEditField = uieditfield(app.RTj_AfterburnerPanelGrid, 'numeric');
            app.RTj_GammaABEditField.Tag = 'RTj_GammaABEditField';
            app.RTj_GammaABEditField.Enable = 'off';
            app.RTj_CpabEditFieldLabel = uilabel(app.RTj_AfterburnerPanelGrid);
            app.RTj_CpabEditFieldLabel.Interpreter = 'latex';
            app.RTj_CpabEditFieldLabel.Tag = 'RTj_CpabEditFieldLabel';
            app.RTj_CpabEditFieldLabel.Enable = 'off';
            app.RTj_CpabEditFieldLabel.Text = '$c_{pAB} \left[kJ /kg K\right]$';
            app.RTj_CpabEditField = uieditfield(app.RTj_AfterburnerPanelGrid, 'numeric');
            app.RTj_CpabEditField.Tag = 'RTj_CpabEditField';
            app.RTj_CpabEditField.Enable = 'off';
            app.RTj_PiabEditFieldLabel = uilabel(app.RTj_AfterburnerPanelGrid);
            app.RTj_PiabEditFieldLabel.Interpreter = 'latex';
            app.RTj_PiabEditFieldLabel.Tag = 'RTj_PiabEditFieldLabel';
            app.RTj_PiabEditFieldLabel.Enable = 'off';
            app.RTj_PiabEditFieldLabel.Text = '$\pi_{AB}$';
            app.RTj_PiabEditField = uieditfield(app.RTj_AfterburnerPanelGrid, 'numeric');
            app.RTj_PiabEditField.Tag = 'RTj_PiabEditField';
            app.RTj_PiabEditField.Enable = 'off';
            app.RTj_EtaabEditFieldLabel = uilabel(app.RTj_AfterburnerPanelGrid);
            app.RTj_EtaabEditFieldLabel.Interpreter = 'latex';
            app.RTj_EtaabEditFieldLabel.Tag = 'RTj_EtaabEditFieldLabel';
            app.RTj_EtaabEditFieldLabel.Enable = 'off';
            app.RTj_EtaabEditFieldLabel.Text = '$\eta_{AB}$';
            app.RTj_EtaabEditField = uieditfield(app.RTj_AfterburnerPanelGrid, 'numeric');
            app.RTj_EtaabEditField.Tag = 'RTj_EtaabEditField';
            app.RTj_EtaabEditField.Enable = 'off';
            app.TurbofanRealTab = uitab(app.EngineInputsPanelTabGroup);
            app.TurbofanRealTab.Title = 'Turbofan Real';
            app.TurbofanRealTab.Tag = 'TurbofanRealTab';
            app.TurbofanRealTabGrid = uigridlayout(app.TurbofanRealTab);
            app.TurbofanRealTabGrid.Tag = 'TurbofanRealTabGrid';
            app.RTf_MachNumberEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_MachNumberEditFieldLabel.Tag = 'RTf_MachNumberEditFieldLabel';
            app.RTf_MachNumberEditFieldLabel.Enable = 'off';
            app.RTf_MachNumberEditFieldLabel.Text = 'Número de Mach';
            app.RTf_MachNumberEditField = uieditfield(app.TurbofanRealTabGrid, 'text');
            app.RTf_MachNumberEditField.ValueChangedFcn = createCallbackFcn(app, @RTf_MachNumberEditFieldValueChanged, true);
            app.RTf_MachNumberEditField.Tag = 'RTf_MachNumberEditField';
            app.RTf_MachNumberEditField.Enable = 'off';
            app.RTf_GammaCEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_GammaCEditFieldLabel.Interpreter = 'latex';
            app.RTf_GammaCEditFieldLabel.Tag = 'RTf_GammaCEditFieldLabel';
            app.RTf_GammaCEditFieldLabel.Enable = 'off';
            app.RTf_GammaCEditFieldLabel.Text = '$\gamma_C$';
            app.RTf_GammaCEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_GammaCEditField.ValueChangedFcn = createCallbackFcn(app, @RTf_GammaCEditFieldValueChanged, true);
            app.RTf_GammaCEditField.Tag = 'RTf_GammaCEditField';
            app.RTf_GammaCEditField.Enable = 'off';
            app.RTf_CpcEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_CpcEditFieldLabel.Interpreter = 'latex';
            app.RTf_CpcEditFieldLabel.Tag = 'RTf_CpcEditFieldLabel';
            app.RTf_CpcEditFieldLabel.Enable = 'off';
            app.RTf_CpcEditFieldLabel.Text = '$c_{pc} \left[kJ /kg K\right]$';
            app.RTf_CpcEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_CpcEditField.ValueChangedFcn = createCallbackFcn(app, @RTf_CpcEditFieldValueChanged, true);
            app.RTf_CpcEditField.Tag = 'RTf_CpcEditField';
            app.RTf_CpcEditField.Enable = 'off';
            app.RTf_HprEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_HprEditFieldLabel.Interpreter = 'latex';
            app.RTf_HprEditFieldLabel.Tag = 'RTf_HprEditFieldLabel';
            app.RTf_HprEditFieldLabel.Enable = 'off';
            app.RTf_HprEditFieldLabel.Text = '$h_{pr} \left[kJ/kg\right]$';
            app.RTf_HprEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_HprEditField.ValueChangedFcn = createCallbackFcn(app, @RTf_HprEditFieldValueChanged, true);
            app.RTf_HprEditField.Tag = 'RTf_HprEditField';
            app.RTf_HprEditField.Enable = 'off';
            app.RTf_Tt4EditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_Tt4EditFieldLabel.Interpreter = 'latex';
            app.RTf_Tt4EditFieldLabel.Tag = 'RTf_Tt4EditFieldLabel';
            app.RTf_Tt4EditFieldLabel.Enable = 'off';
            app.RTf_Tt4EditFieldLabel.Text = '$T_{t4} [K]$';
            app.RTf_Tt4EditField = uieditfield(app.TurbofanRealTabGrid, 'text');
            app.RTf_Tt4EditField.ValueChangedFcn = createCallbackFcn(app, @RTf_Tt4EditFieldValueChanged, true);
            app.RTf_Tt4EditField.Tag = 'RTf_Tt4EditField';
            app.RTf_Tt4EditField.Enable = 'off';
            app.RTf_FlightRegimeEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_FlightRegimeEditFieldLabel.Interpreter = 'latex';
            app.RTf_FlightRegimeEditFieldLabel.Tag = 'RTf_FlightRegimeEditFieldLabel';
            app.RTf_FlightRegimeEditFieldLabel.Enable = 'off';
            app.RTf_FlightRegimeEditFieldLabel.Text = '$T_0 [K]$';
            app.RTf_FlightRegimeEditField = uieditfield(app.TurbofanRealTabGrid, 'text');
            app.RTf_FlightRegimeEditField.ValueChangedFcn = createCallbackFcn(app, @RTf_FlightRegimeEditFieldValueChanged, true);
            app.RTf_FlightRegimeEditField.Tag = 'RTf_FlightRegimeEditField';
            app.RTf_FlightRegimeEditField.Enable = 'off';
            app.RTf_FlightRegimeGridCheckbox = uigridlayout(app.TurbofanRealTabGrid);
            app.RTf_FlightRegimeGridCheckbox.Tag = 'RTf_FlightRegimeGridCheckbox';
            app.RTf_AltitudeCheckbox = uicheckbox(app.RTf_FlightRegimeGridCheckbox);
            app.RTf_AltitudeCheckbox.ValueChangedFcn = createCallbackFcn(app, @RTf_AltitudeCheckboxValueChanged, true);
            app.RTf_AltitudeCheckbox.Tag = 'RTf_AltitudeCheckbox';
            app.RTf_AltitudeCheckbox.Enable = 'off';
            app.RTf_AltitudeCheckbox.Text = 'Altitude';
            app.RTf_TemperatureCheckbox = uicheckbox(app.RTf_FlightRegimeGridCheckbox);
            app.RTf_TemperatureCheckbox.ValueChangedFcn = createCallbackFcn(app, @RTf_TemperatureCheckboxValueChanged, true);
            app.RTf_TemperatureCheckbox.Tag = 'RTf_TemperatureCheckbox';
            app.RTf_TemperatureCheckbox.Enable = 'off';
            app.RTf_TemperatureCheckbox.Text = 'Temperatura';
            app.RTf_TemperatureCheckbox.Value = true;
            app.RTf_GammaTEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_GammaTEditFieldLabel.Interpreter = 'latex';
            app.RTf_GammaTEditFieldLabel.Tag = 'RTf_GammaTEditFieldLabel';
            app.RTf_GammaTEditFieldLabel.Enable = 'off';
            app.RTf_GammaTEditFieldLabel.Text = '$\gamma_T$';
            app.RTf_GammaTEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_GammaTEditField.Tag = 'RTf_GammaTEditField';
            app.RTf_GammaTEditField.Enable = 'off';
            app.RTf_CptEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_CptEditFieldLabel.Interpreter = 'latex';
            app.RTf_CptEditFieldLabel.Tag = 'RTf_CptEditFieldLabel';
            app.RTf_CptEditFieldLabel.Enable = 'off';
            app.RTf_CptEditFieldLabel.Text = '$c_{pt} \left[kJ /kg K\right]$';
            app.RTf_CptEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_CptEditField.Tag = 'RTf_CptEditField';
            app.RTf_CptEditField.Enable = 'off';
            app.RTf_P0P9EditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_P0P9EditFieldLabel.Interpreter = 'latex';
            app.RTf_P0P9EditFieldLabel.Tag = 'RTf_P0P9EditFieldLabel';
            app.RTf_P0P9EditFieldLabel.Enable = 'off';
            app.RTf_P0P9EditFieldLabel.Text = '$P_0/P_9$';
            app.RTf_P0P9EditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_P0P9EditField.Tag = 'RTf_P0P9EditField';
            app.RTf_P0P9EditField.Enable = 'off';
            app.RTf_P0P19EditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_P0P19EditFieldLabel.Interpreter = 'latex';
            app.RTf_P0P19EditFieldLabel.Tag = 'RTf_P0P19EditFieldLabel';
            app.RTf_P0P19EditFieldLabel.Enable = 'off';
            app.RTf_P0P19EditFieldLabel.Text = '$P_0/P_{19}$';
            app.RTf_P0P19EditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_P0P19EditField.Tag = 'RTf_P0P19EditField';
            app.RTf_P0P19EditField.Enable = 'off';
            app.RTf_BPREditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_BPREditFieldLabel.Tag = 'RTf_BPREditFieldLabel';
            app.RTf_BPREditFieldLabel.Enable = 'off';
            app.RTf_BPREditFieldLabel.Text = 'BPR';
            app.RTf_BPREditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_BPREditField.Tag = 'RTf_BPREditField';
            app.RTf_BPREditField.Enable = 'off';
            app.RTf_PicEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PicEditFieldLabel.Interpreter = 'latex';
            app.RTf_PicEditFieldLabel.Tag = 'RTf_PicEditFieldLabel';
            app.RTf_PicEditFieldLabel.Enable = 'off';
            app.RTf_PicEditFieldLabel.Text = '$\pi_{C}$';
            app.RTf_PicEditField = uieditfield(app.TurbofanRealTabGrid, 'text');
            app.RTf_PicEditField.Tag = 'RTf_PicEditField';
            app.RTf_PicEditField.Enable = 'off';
            app.RTf_PifEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PifEditFieldLabel.Interpreter = 'latex';
            app.RTf_PifEditFieldLabel.Tag = 'RTf_PifEditFieldLabel';
            app.RTf_PifEditFieldLabel.Enable = 'off';
            app.RTf_PifEditFieldLabel.Text = '$\pi_{F}$';
            app.RTf_PifEditField = uieditfield(app.TurbofanRealTabGrid, 'text');
            app.RTf_PifEditField.Tag = 'RTf_PifEditField';
            app.RTf_PifEditField.Enable = 'off';
            app.RTf_PidmaxEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PidmaxEditFieldLabel.Interpreter = 'latex';
            app.RTf_PidmaxEditFieldLabel.Tag = 'RTf_PidmaxEditFieldLabel';
            app.RTf_PidmaxEditFieldLabel.Enable = 'off';
            app.RTf_PidmaxEditFieldLabel.Text = '$\pi_{D max}$';
            app.RTf_PidmaxEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_PidmaxEditField.Tag = 'RTf_PidmaxEditField';
            app.RTf_PidmaxEditField.Enable = 'off';
            app.RTf_PibEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PibEditFieldLabel.Interpreter = 'latex';
            app.RTf_PibEditFieldLabel.Tag = 'RTf_PibEditFieldLabel';
            app.RTf_PibEditFieldLabel.Enable = 'off';
            app.RTf_PibEditFieldLabel.Text = '$\pi_{B}$';
            app.RTf_PibEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_PibEditField.Tag = 'RTf_PibEditField';
            app.RTf_PibEditField.Enable = 'off';
            app.RTf_PinEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PinEditFieldLabel.Interpreter = 'latex';
            app.RTf_PinEditFieldLabel.Tag = 'RTf_PinEditFieldLabel';
            app.RTf_PinEditFieldLabel.Enable = 'off';
            app.RTf_PinEditFieldLabel.Text = '$\pi_{N}$';
            app.RTf_PinEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_PinEditField.Tag = 'RTf_PinEditField';
            app.RTf_PinEditField.Enable = 'off';
            app.RTf_PifnEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_PifnEditFieldLabel.Interpreter = 'latex';
            app.RTf_PifnEditFieldLabel.Enable = 'off';
            app.RTf_PifnEditFieldLabel.Text = '$\pi_{FN}$';
            app.RTf_PifnEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_PifnEditField.Tag = 'RTf_PifnEditField';
            app.RTf_PifnEditField.Enable = 'off';
            app.RTf_EcEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_EcEditFieldLabel.Interpreter = 'latex';
            app.RTf_EcEditFieldLabel.Tag = 'RTf_EcEditFieldLabel';
            app.RTf_EcEditFieldLabel.Enable = 'off';
            app.RTf_EcEditFieldLabel.Text = '$e_{C}$';
            app.RTf_EcEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_EcEditField.Tag = 'RTf_EcEditField';
            app.RTf_EcEditField.Enable = 'off';
            app.RTf_EtEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_EtEditFieldLabel.Interpreter = 'latex';
            app.RTf_EtEditFieldLabel.Tag = 'RTf_EtEditFieldLabel';
            app.RTf_EtEditFieldLabel.Enable = 'off';
            app.RTf_EtEditFieldLabel.Text = '$e_{T}$';
            app.RTf_EtEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_EtEditField.Tag = 'RTf_EtEditField';
            app.RTf_EtEditField.Enable = 'off';
            app.RTf_EfEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_EfEditFieldLabel.Interpreter = 'latex';
            app.RTf_EfEditFieldLabel.Tag = 'RTf_EfEditFieldLabel';
            app.RTf_EfEditFieldLabel.Enable = 'off';
            app.RTf_EfEditFieldLabel.Text = '$e_{F}$';
            app.RTf_EfEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_EfEditField.Tag = 'RTf_EfEditField';
            app.RTf_EfEditField.Enable = 'off';
            app.RTf_EtabEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_EtabEditFieldLabel.Interpreter = 'latex';
            app.RTf_EtabEditFieldLabel.Tag = 'RTf_EtabEditFieldLabel';
            app.RTf_EtabEditFieldLabel.Enable = 'off';
            app.RTf_EtabEditFieldLabel.Text = '$\eta_{B}$';
            app.RTf_EtabEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_EtabEditField.Tag = 'RTf_EtabEditField';
            app.RTf_EtabEditField.Enable = 'off';
            app.RTf_EtamEditFieldLabel = uilabel(app.TurbofanRealTabGrid);
            app.RTf_EtamEditFieldLabel.Interpreter = 'latex';
            app.RTf_EtamEditFieldLabel.Tag = 'RTf_EtamEditFieldLabel';

            app.RTf_EtamEditFieldLabel.Enable = 'off';
            app.RTf_EtamEditFieldLabel.Text = '$\eta_{M}$';
            app.RTf_EtamEditField = uieditfield(app.TurbofanRealTabGrid, 'numeric');
            app.RTf_EtamEditField.Tag = 'RTf_EtamEditField';
            app.RTf_EtamEditField.Enable = 'off';
            app.ManterInputsComunsCheckbox = uicheckbox(app.EngineInputsPanelGrid);
            app.ManterInputsComunsCheckbox.ValueChangedFcn = createCallbackFcn(app, @ManterInputsComunsCheckboxValueChanged, true);
            app.ManterInputsComunsCheckbox.Enable = 'off';
            app.ManterInputsComunsCheckbox.Tooltip = {'Ao clicar os inputs que são comuns a todos os motores serão os mesmos'};
            app.ManterInputsComunsCheckbox.Text = 'Manter inputs comuns';
            app.InputsCOMPILARButton = uibutton(app.EngineInputsPanelGrid, 'push');
            app.InputsCOMPILARButton.ButtonPushedFcn = createCallbackFcn(app, @InputsCOMPILARButtonPushed, true);
            app.InputsCOMPILARButton.Enable = 'off';
            app.InputsCOMPILARButton.Text = 'COMPILAR';
            app.AnalysisResultPanel = uipanel(app.GridLayout);
            app.AnalysisResultPanel.Tooltip = {'PASSO 3: RESULTADOS'};
            app.AnalysisResultPanel.Title = 'PASSO 3: RESULTADOS';
            app.AnalysisResultPanelGrid = uigridlayout(app.AnalysisResultPanel);
            app.AnalysisResultPanelTabGroup = uitabgroup(app.AnalysisResultPanelGrid);
            app.TabelaTab = uitab(app.AnalysisResultPanelTabGroup);
            app.TabelaTab.Title = 'Tabela';
            app.TabelaTabGrid = uigridlayout(app.TabelaTab);
            app.UITable = uitable(app.TabelaTabGrid);
            app.UITable.ColumnName = {'Tt4'; 'FlightRegimeValue'; 'piC'; 'piF'; 'Mach'; 'Empuxo específico [ N/(kg/s) ]'; 'Consumo específico [ (kg/s)/N ]'; 'Razão combustível-ar'; 'Eficiência térmica'; 'Eficiência propulsiva'; 'Eficiência total'};
            app.UITable.RowName = {};
            app.ExportTableButton = uibutton(app.TabelaTabGrid, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Enable = 'off';
            app.ExportTableButton.Visible = 'off';
            app.ExportTableButton.Text = 'EXPORTAR';
            app.TextArea = uitextarea(app.TabelaTabGrid);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Os inputs informados produziram um grande número de resultados. Para visualizar os valores referentes aos inputs informados clique no botão ao lado para exportar os dados para uma planilha.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parametricCycleAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end